classdef matlab_basic_plotting < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        Plot3dAxes  matlab.ui.control.UIAxes
        SourceAxes  matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function plotshape(app,p,cm)
            [numtri,~] = size(cm);
            for tri = 1:numtri
                ptri(:,1) = p(cm(tri,1)+1,:);
                ptri(:,2) = p(cm(tri,2)+1,:);
                ptri(:,3) = p(cm(tri,3)+1,:);
                ptri(:,4) = p(cm(tri,1)+1,:);
                x=ptri(1,:);
                y=ptri(2,:);
                z=ptri(3,:);
                line(app.Plot3dAxes,x,y,z)
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
%             fileID = fopen('ray_trace\src\data_transfer.txt');
%             file = fscanf(fileID,'%s');
%             for i = 1:length(file)
%                 if strcmp([file(i) file(i+1) file(i+2) file(i+3)],'pacc')
%                     pacci = i;
%                     break
%                 end
%             end
%             for i = 1:length(file)
%                 if strcmp([file(i) file(i+1) file(i+2) file(i+3) file(i+4)],'upacc')
%                     upacci = i;
%                     break
%                 end
%             end
%             for i = 1:length(file)
%                 if strcmp([file(i) file(i+1) file(i+2) file(i+3)],'dacc')
%                     dacci = i;
%                     break
%                 end
%             end
%             for i = 1:length(file)
%                 if strcmp([file(i) file(i+1)],'cm')
%                     cmi = i;
%                     break
%                 end
%             end
%             for i = 1:length(file)
%                 if strcmp([file(i) file(i+1)],'sp')
%                     spi = i;
%                     break
%                 end
%             end
%             
%             
%             i=length('pacc')+pacci;
%             pacc = {};
%             celli =1;
%             
%             while ~strcmp([file(i) file(i+1) file(i+2) file(i+3) file(i+4)],'upacc')
%                 if strcmp(file(i),'[')
%                     i = i+1;
%                     continue
%                 end
%                 j=i;
%                 while ~strcmp(file(j),']')
%                     j = j + 1; % find end
%                 end
%                 if j~=i
%                     pacc(celli) = {file(i:j-1)};
%                     celli = celli + 1;
%                     i = j+1;
%                 else
%                     i = i+1;
%                 end
%             end
%             
%             i= length('upacc')+upacci;
%             upacc = {};
%             celli =1;
%             
%             while ~strcmp([file(i) file(i+1) file(i+2) file(i+3)],'dacc')
%                 if strcmp(file(i),'[')
%                     i = i+1;
%                     continue
%                 end
%                 j=i;
%                 while ~strcmp(file(j),']')
%                     j = j + 1; % find end
%                 end
%                 if j~=i
%                     upacc(celli) = {file(i:j-1)};
%                     celli = celli + 1;
%                     i = j+1;
%                 else
%                     i = i+1;
%                 end
%             end
%             
%             
%             
            
            upacc = [0.         0.         1. ;       
       0.         0.         1.        ;
       0.         0.         1.        ;
       0.         0.         1.        ;
       0.         0.         1.        ;
       0.         0.         1.        ;
       0.         0.         1.        ;
       0.         0.         1.        ;
       0.         0.         1.        ;
       0.08135488, 0.16270976, 0.98331425;
       0.08135488, 0.16270976, 0.98331425;
       0.08135488, 0.16270976, 0.98331425];
   
            pacc = [0.   0.   0. ; 
       0.   1.   0.  ;
       0.   2.   0. ;
       1.   0.   0.  ;
       1.   1.   0.  ;
       1.   2.   0.  ;
       2.   0.   0.  ;
       2.   1.   0.  ;
       2.   2.   0.  ;
       1.   2.   2.75;
       2.   1.   2.75;
       2.   2.   2.75];
            
            dacc = [0   0   0.0   0.0   0.0   3.0   0.0   3.25 2.75 0   0   0  ];
            
            dacc(dacc==0)=1;
            
            rdacc = repmat(dacc,3,1)';
            dir = upacc.*rdacc;
            
            quiver3(app.Plot3dAxes,pacc(:,1),pacc(:,2),pacc(:,3),dir(:,1),dir(:,2),dir(:,3),1)
            
            sp =  [-1 -1 1;
                  3 3 2;
                  -1 3 3;
                  3 -1 4];
            cm =[0 2 3; 1 2 3;];
            
            plotshape(app,sp,cm)
            
            h=1
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Plot3dAxes = uiaxes(app.UIFigure);
            title(app.Plot3dAxes, 'Plot 3D')
            xlabel(app.Plot3dAxes, 'X')
            ylabel(app.Plot3dAxes, 'Y')
            app.SourceAxes = uiaxes(app.UIFigure);
            title(app.SourceAxes, 'Source')
            xlabel(app.SourceAxes, 'X')
            ylabel(app.SourceAxes, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = matlab_basic_plotting

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end